classdef FaultClassifierApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ResultLabelLamp              matlab.ui.control.Lamp
        FeaturesLabel                matlab.ui.control.Label
        FeaturesListBox              matlab.ui.control.ListBox
        ACFDSAllrightsreservedLabel  matlab.ui.control.Label
        HelpButton                   matlab.ui.control.Button
        FeedbackButton               matlab.ui.control.Button
        TermsandConditionsButton     matlab.ui.control.Button
        StatusLabelLamp              matlab.ui.control.Lamp
        StatusLabel                  matlab.ui.control.Label
        ResultLabel                  matlab.ui.control.Label
        MainControlPanel             matlab.ui.container.Panel
        PlayButton                   matlab.ui.control.Button
        ContactusButton              matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        VolumeSlider                 matlab.ui.control.Slider
        VolumeSliderLabel            matlab.ui.control.Label
        FeaturesButton               matlab.ui.control.Button
        ClassifyButton               matlab.ui.control.Button
        LoadAudioButton              matlab.ui.control.Button
        VisualizationPanel           matlab.ui.container.Panel
        DropDown                     matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        samplingRate % Also known as 'Fs'
        file 
        path 
        signal 
        scalingFactor = 1; 
        updateStatus 
        AudioData
        datastr
        Fs   
        FeedbackTextArea
        Features
        ClassificationResult
        AudioAxes
        currentVolume
        audioData
        scaledSignal


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadAudioButton
        function loadAudioCallback(app, event)
                % Opening the file selection dialog
            [app.file, app.path] = uigetfile({'*.wav;*.mp3', 'Audio Files (*.wav, *.mp3)'});
            
            % Checking if the file given even exists
            fullFileName = fullfile(app.path, app.file);
            if ~isfile(fullFileName)
                return; % User canceled selection
            end
            
            % Loading the audio file
            [app.signal, app.samplingRate] = audioread(fullfile(app.path, app.file)); 
            
            % Checking if the user canceled the file selection
            if app.file == 0
                disp('User canceled file selection.');
                return;
            end
            
            % Plotting the waveform on the UIAxes
            plot(app.UIAxes, (1:length(app.signal))/app.samplingRate, app.signal); 
            title(app.UIAxes, 'Waveform');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            
            % Optional: scaling the audio (used later if needed, but not playing it)
            app.scaledSignal = app.signal .* app.scalingFactor;
            app.scaledSignal = max(min(app.scaledSignal, 1), -1);
            
            % Note: Audio playback removed as per your request

        
        end

        % Button pushed function: FeedbackButton
        function feedbackButtonPushed(app, event)
        prompt = {'Enter your feedback:'};
        dlgtitle = 'Send Feedback';
        dims = [5 50]; 
        definput = {''};
        answer = inputdlg(prompt, dlgtitle, dims, definput);
        if ~isempty(answer)
            feedbackText = answer{1};
            try
                filename = 'feedback.txt';
                fileID = fopen(filename, 'a');
                if fileID ~= -1
                    fprintf(fileID, '%s\n', feedbackText);
                    fclose(fileID);
                    uialert(app.UIFigure, 'Feedback saved to feedback.txt', 'Feedback Sent');
                else
                    uialert(app.UIFigure, 'Error saving feedback file.', 'Error');
                end
            catch ME
                uialert(app.UIFigure, ['Error saving feedback: ', ME.message], 'Error');
            end 
        else  
        end
        
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
               app.scalingFactor = app.VolumeSlider.Value;
        end

        % Button pushed function: PlayButton
        function playAgainButtonPushed(app, event)
           % Scaling the audio & clipping it when it goes beyond -1 or 1.
            app.scaledSignal = app.signal .* app.scalingFactor;
            app.scaledSignal = max(min(app.scaledSignal, 1), -1);

           
            sound(app.scaledSignal, app.samplingRate);

        end

        % Button down function: UIAxes
        function plotAudioWaveform(app, event)
            cla(app.AudioAxes);
            
            % Calculate time axis
            duration = size(app.AudioData, 1) / app.SampleRate;
            t = linspace(0, duration, size(app.AudioData, 1));
            
            % Plot all channels
            hold(app.AudioAxes, 'on');
            for i = 1:size(app.AudioData, 2)
                plot(app.AudioAxes, t, app.AudioData(:,i)); 
            end
            hold(app.AudioAxes, 'off');
            
            % Format the axes
            app.AudioAxes.XLabel.String = 'Time (seconds)';
            app.AudioAxes.YLabel.String = 'Amplitude';
            app.AudioAxes.Title.String = 'Audio Waveform';
            grid(app.AudioAxes, 'on');
            
            % Set appropriate limits
            ymax = max(abs(app.AudioData(:))) * 1.1;
            app.AudioAxes.YLim = [-ymax, ymax];
            app.AudioAxes.XLim = [0, duration];
        end

        % Button pushed function: ClassifyButton
        function classifyAudio(app, event)
             
            addpath(fullfile('', 'Functions'));
            addpath(fullfile('', 'Scripts'));
            addpath(fullfile('', 'Data', 'UnknownRecordings', 'UnknownRecordings'));
            addpath(fullfile('Functions'));
            addpath(fullfile('Scripts'));
            addpath(fullfile('Data', 'UnknownRecordings', 'UnknownRecordings'));
            load("bestModel","trainedModel");
            app.StatusLabel.Text = 'Delivering the predicted result... ';
            pause(4)

            
            app.FeaturesLabel.Text = 'Features';
            
            predLabel = classifyCompressorFault(app.file,trainedModel);
            app.StatusLabel.Text = 'Predicted result delivered... ';
            app.StatusLabelLamp.Color = [0.00,1.00,0.00];
            app.ResultLabelLamp.Color = [0.00,1.00,0.00];
            app.ResultLabel.Text = sprintf('Predicted fault for \n%s is: \n%s\n', app.file, string(predLabel));
        end

        % Button pushed function: FeaturesButton
        function features(app, event)
                % Adding paths
                addpath(fullfile('', 'Functions'));
                addpath(fullfile('Functions'));
                
                features = extractAudioFeatures(app.signal, app.samplingRate);
                
                % Updating progress
                app.StatusLabel.Text = 'Listing the Features .... ';
                pause(4)
                
                % Building the table
                FeaturesTable14features = array2table(zeros(1, 14), ...
                    'VariableNames', features.Properties.VariableNames);
                FeaturesTable14features{1, 1:14} = features{1, 1:14};
                
                % Displaying the table
                app.StatusLabel.Text = 'Features Listed... ';
                app.StatusLabelLamp.Color = [0.00,1.00,0.00];
                app.ResultLabelLamp.Color = [0.00,1.00,0.00];
                app.FeaturesListBox.Items = FeaturesTable14features.Properties.VariableNames;
                app.FeaturesLabel.Text = sprintf('Features : %s', app.file);
                
        end

        % Drop down opening function: DropDown
        function DropDownValueChanged(app, event)
            
       
           displayType = app.DropDown.Value;
            if strcmp(displayType, 'Waveform')
                % Clearing the UIAxes
                if isvalid(app.UIAxes)
                    cla(app.UIAxes);
                end
                
                % Plotting the waveform on the UIAxes
                plot(app.UIAxes, (1:length(app.signal))/app.samplingRate, app.signal); 
                title(app.UIAxes, 'Waveform');
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Amplitude');
            else
                % Clearing the UIAxes
                if isvalid(app.UIAxes)
                    cla(app.UIAxes);
                end

                % Plotting the spectrogram on the UIAxes
                axes(app.UIAxes);
                [~, F, T, P] = spectrogram(app.signal, 256, 250, 256, app.samplingRate);
                imagesc(app.UIAxes, T, F, 10*log10(P));
                axis(app.UIAxes, 'xy');
                colormap(app.UIAxes, jet);
                colorbar(app.UIAxes);

                % The title and x and y labels (below)
                title(app.UIAxes, 'Spectrogram');
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Frequency (Hz)');
                colorbar;
            end

        end

        % Button pushed function: Button
        function resetButtonPushed(app, event)
                app.file = [];
                app.path = [];
                app.signal = [];
                app.samplingRate = [];
                % Resetting the relevant variables of our table to be empty
                app.FeaturesListBox.Items = {};
                app.FeaturesLabel.Text = 'Features';
                % Callback function for the Reset Button
                cla(app.UIAxes);
                
                xlabel(app.UIAxes, ' Time (seconds)')
                ylabel(app.UIAxes, ' Amplitude')
                zlabel(app.UIAxes, 'Z')
                app.UIAxes.XColor = [1 1 1];
                app.UIAxes.YColor = [1 1 1];
                app.UIAxes.BoxStyle = 'full';
                app.UIAxes.Color = [0 0 0];
                app.UIAxes.MinorGridColor = [1 1 1];
                app.UIAxes.GridAlpha = 0.25;
                app.UIAxes.Box = 'on';
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                app.UIAxes.YMinorGrid = 'on';
                app.UIAxes.FontSize = 11;
                app.UIAxes.TitleFontWeight = 'normal';
                app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @plotAudioWaveform, true);
                app.UIAxes.Position = [7 3 533 214];
                
                % Resetting the relevant variables of our ResultLabel to be empty
                app.ResultLabel.Text = 'Result Label';
                % Resetting the relevant variables of our axis to be empty
                
                % Resetting our volume's scaling factor
                app.scalingFactor = 1;
                % Resetting our status label's settings
                app.StatusLabel.Text = 'Status Label';
                % Resetting our lamp's RGB settings
                app.StatusLabelLamp.Color = [1.00,0.00,0.00];
                app.ResultLabelLamp.Color = [1.00,0.00,0.00];
                % Resetting the value of our drop-down button
                app.DropDown.Value = 'Waveform';
         
    
        end

        % Button pushed function: ContactusButton
        function ContactUsButtonPushed(app, event)
                     % Define contact details
            contactNumber = '+94 77 123 4567';  % Replace with your actual contact number
            contactEmail = 'contactus@acfds.co.uk';  % Replace with your actual email
        
            % Create the message to display
            contactMessage = sprintf('📞 Contact Number: %s\n📧 Email: %s', contactNumber, contactEmail);
        
            % Display the message in a UI alert box
            uialert(app.UIFigure, contactMessage, 'Contact Us', 'Icon', 'info');
        end

        % Button pushed function: TermsandConditionsButton
        function TermsAndConditionsButtonPushed(app, event)
            % Define the terms and conditions text
                termsText = [
                    "Terms and Conditions"
                    "-------------------------"
                    "1. This software is provided as-is without any warranties."
                    "2. The user agrees not to misuse the application for illegal purposes."
                    "3. The developers are not responsible for any damages resulting from the use of this software."
                    "4. Data collected by this application is solely for analysis and classification purposes."
                    "5. By using this application, you agree to these terms."
                ];
            
                % Convert cell array to a single string with new lines
                termsMessage = strjoin(termsText, '\n');
            
                % Display the terms in a UI alert box
                uialert(app.UIFigure, termsMessage, 'Terms and Conditions', 'Icon', 'info');
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helpText = [ ...
                        '📘 AIR COMPRESSOR FAULT DETECTION SYSTEM - HELP GUIDE' newline newline ...
                        '🔹 SUPPORTED FILE TYPES:' newline ...
                        '   - Only the following audio formats are supported:' newline ...
                        '     • .wav files' newline ...
                        '     • .mp3 files' newline newline ...
                        '🔄 IMPORTANT:' newline ...
                        '   - You must click the RESET button before loading each new audio file.' newline newline ...
                        '🛠️ HOW TO USE THE APP (Step-by-Step):' newline ...
                        '1️⃣ Step 1: Click the RESET button to clear any previous data(if needed).' newline ...
                        '2️⃣ Step 2: Click the "Load Audio" button and select a .wav or .mp3 file.' newline ...
                        '3️⃣ Step 3: Adjust the volume using the slider (if needed).' newline ...
                        '4️⃣ Step 4: Click the "Play Again" button to listen to the loaded audio.' newline ...
                        '5️⃣ Step 5: Click the "Features" button to extract and display audio features.' newline ...
                        '6️⃣ Step 6: Click the "Classify" button to detect and show the fault label.' newline ...
                        '7️⃣ Step 7: Review the predicted fault and listed features on the right panel.' newline ...
                        '8️⃣ Step 8: Use "Contact us" or "Feedback" if you need help or want to give suggestions.' newline newline ...
                        '🔘 BUTTON FUNCTIONS:' newline ...
                        '• Load Audio     - Load an audio file (.wav or .mp3) for analysis.' newline ...
                        '• RESET          - Reset the app to its initial state. Must be used before loading new audio.' newline ...
                        '• Play Again     - Replay the currently loaded audio signal.' newline ...
                        '• Features       - Extract and display audio features like RMS, Skewness, Kurtosis, etc.' newline ...
                        '• Classify       - Detect the fault from the audio using the trained model.' newline ...
                        '• Contact us     - Get contact info or support form to reach the developer/support team.' newline ...
                        '• Feedback       - Provide feedback or suggestions to improve the app.' newline ...
                        '• Terms and Conditions - View the app''s legal terms and privacy policy.' newline ...
                        '• Volume Slider  - Adjust the playback volume from 0 to 100.' newline newline ...
                        '© 2025 ACFDS - All rights reserved.' ...
                        ];
            
            uialert(app.UIFigure, helpText, 'Help Documentation', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'layers', 'Bg.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'layers', 'title .png');
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            xlabel(app.UIAxes, ' Time (seconds)')
            ylabel(app.UIAxes, ' Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @plotAudioWaveform, true);
            app.DropDown = uidropdown(app.VisualizationPanel);
            app.DropDown.Items = {'Waveform', 'Spectogram', 'Power Spectrum'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Waveform';
            app.MainControlPanel = uipanel(app.UIFigure);
            app.MainControlPanel.Title = 'Main Control';
            app.LoadAudioButton = uibutton(app.MainControlPanel, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @loadAudioCallback, true);
            app.LoadAudioButton.Icon = fullfile(pathToMLAPP, 'layers', 'upload.png');
            app.LoadAudioButton.Text = 'Load Audio';
            app.ClassifyButton = uibutton(app.MainControlPanel, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @classifyAudio, true);
            app.ClassifyButton.Icon = fullfile(pathToMLAPP, 'layers', 'block-scheme-of-geometrical-shapes.png');
            app.ClassifyButton.Text = 'Classify';
            app.FeaturesButton = uibutton(app.MainControlPanel, 'push');
            app.FeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @features, true);
            app.FeaturesButton.Icon = fullfile(pathToMLAPP, 'layers', 'application.png');
            app.FeaturesButton.Text = 'Features';
            app.VolumeSliderLabel = uilabel(app.MainControlPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.MainControlPanel);
            app.VolumeSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Value = 100;
            app.Button = uibutton(app.MainControlPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'layers', 'reset.png');
            app.Button.Text = '';
            app.ContactusButton = uibutton(app.MainControlPanel, 'push');
            app.ContactusButton.ButtonPushedFcn = createCallbackFcn(app, @ContactUsButtonPushed, true);
            app.ContactusButton.Text = 'Contact us';
            app.PlayButton = uibutton(app.MainControlPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @playAgainButtonPushed, true);
            app.PlayButton.Icon = 'play-button-icon-vector-4.jpg';
            app.PlayButton.Text = 'Play';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result Label';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status Label';
            app.StatusLabelLamp = uilamp(app.UIFigure);
            app.TermsandConditionsButton = uibutton(app.UIFigure, 'push');
            app.TermsandConditionsButton.ButtonPushedFcn = createCallbackFcn(app, @TermsAndConditionsButtonPushed, true);
            app.TermsandConditionsButton.Text = 'Terms and Conditions';
            app.FeedbackButton = uibutton(app.UIFigure, 'push');
            app.FeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @feedbackButtonPushed, true);
            app.FeedbackButton.Text = 'Feedback';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = fullfile(pathToMLAPP, 'layers', 'help-web-button.png');
            app.HelpButton.Text = 'Help';
            app.ACFDSAllrightsreservedLabel = uilabel(app.UIFigure);
            app.ACFDSAllrightsreservedLabel.Text = '@2025 ACFDS-All rights reserved';
            app.FeaturesListBox = uilistbox(app.UIFigure);
            app.FeaturesListBox.Items = {};
            app.FeaturesListBox.Value = {};
            app.FeaturesLabel = uilabel(app.UIFigure);
            app.FeaturesLabel.Text = 'Features ';
            app.ResultLabelLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FaultClassifierApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end